classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DeleteprojectionswhenquittingCheckBox  matlab.ui.control.CheckBox
        SourcetoDetectorDistanceinpixelsEditField  matlab.ui.control.NumericEditField
        SourcetoDetectorDistanceinpixelsEditFieldLabel  matlab.ui.control.Label
        LengthoftheDetectorEditField  matlab.ui.control.NumericEditField
        LengthoftheDetectorEditFieldLabel  matlab.ui.control.Label
        SpecificAngleEditField        matlab.ui.control.NumericEditField
        FilterButtonGroup             matlab.ui.container.ButtonGroup
        BartlettHannButton            matlab.ui.control.RadioButton
        HammingButton                 matlab.ui.control.RadioButton
        RamLakButton                  matlab.ui.control.RadioButton
        NoneButton                    matlab.ui.control.RadioButton
        SpecificAngleEditFieldLabel   matlab.ui.control.Label
        BACKPROJECTButton             matlab.ui.control.Button
        PROJECTButton                 matlab.ui.control.Button
        ProjectionAngleStepSizeindegreesEditField  matlab.ui.control.NumericEditField
        ProjectionAngleStepSizeindegreesEditFieldLabel  matlab.ui.control.Label
        NumberofDetectorsEditField    matlab.ui.control.NumericEditField
        NumberofDetectorsEditFieldLabel  matlab.ui.control.Label
        PhantomFileRelativePathEditField  matlab.ui.control.EditField
        PhantomFileRelativePathLabel  matlab.ui.control.Label
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PROJECTButton
        function PROJECTButtonPushed(app, event)
            phantom_path = app.PhantomFileRelativePathEditField.Value;
            I1 = struct2array(load(phantom_path));
            L_detector = app.LengthoftheDetectorEditField.Value;
            N_detectors = app.NumberofDetectorsEditField.Value;
            projection_angle_step_size = app.ProjectionAngleStepSizeindegreesEditField.Value;
            source2det_dist = app.SourcetoDetectorDistanceinpixelsEditField.Value;
            P1 = radon_project(phantom_path,L_detector, N_detectors, projection_angle_step_size, source2det_dist);
            imagesc(P1,'Parent',app.UIAxes); colormap(app.UIAxes,gray);
            xlim(app.UIAxes,[0, size(P1,2)]);
            ylim(app.UIAxes,[0, size(P1,1)]);
            angle = app.SpecificAngleEditField.Value;
            idx = round(angle / (app.ProjectionAngleStepSizeindegreesEditField.Value));
            plot(1:length(P1(:,idx)),(P1(:,idx)),'Parent',app.UIAxes3)
            colormap(app.UIAxes3,gray)
            imagesc(I1,'Parent',app.UIAxes5); 
            xlim(app.UIAxes5,[0, size(I1,1)]);
            ylim(app.UIAxes5,[0, size(I1,1)]);
            colormap(app.UIAxes5,gray)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: BACKPROJECTButton
        function BACKPROJECTButtonPushed(app, event)
            phantom_path = app.PhantomFileRelativePathEditField.Value;
            I1 = struct2array(load(phantom_path));
            M = size(I1,1);
            splits = split(phantom_path,'/');
            file_name = splits{2};
            PROJECTIONS = struct2array(load([file_name, '_projections']));
            L_detector = app.LengthoftheDetectorEditField.Value;
            N_detectors = app.NumberofDetectorsEditField.Value;
            source2det_dist = app.SourcetoDetectorDistanceinpixelsEditField.Value;

            R1 = real(back_projection(M, M, PROJECTIONS, L_detector, source2det_dist, N_detectors));
            imagesc(R1,'Parent',app.UIAxes4); 
            xlim(app.UIAxes4,[0, M]);
            ylim(app.UIAxes4,[0, M]);
            colormap(app.UIAxes4,gray)
            colormap(app.UIAxes2,gray)
            button = app.FilterButtonGroup.SelectedObject;
            switch button.Text
                case 'None'
                    R1 = real(back_projection(M, M, PROJECTIONS, L_detector, source2det_dist, N_detectors));
                case 'Ram-Lak'
                    R1 = real(filtered_back_projection_ram(M, M, PROJECTIONS, L_detector, source2det_dist, N_detectors));
                case 'Bartlett-Hann'
                    R1 = real(filtered_back_projection_bart(M, M, PROJECTIONS, L_detector, source2det_dist, N_detectors));
                case 'Hamming'
                    R1 = real(filtered_back_projection_hamm(M, M, PROJECTIONS, L_detector, source2det_dist, N_detectors));
            end
            imagesc(R1,'Parent',app.UIAxes2); 
            xlim(app.UIAxes2,[0, M]);
            ylim(app.UIAxes2,[0, M]);
            colormap(app.UIAxes2,gray)

        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.FilterButtonGroup.SelectedObject;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.DeleteprojectionswhenquittingCheckBox.Value
                delete *.mat
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sonogram')
            xlabel(app.UIAxes, 'Angle')
            ylabel(app.UIAxes, 'Beams')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered-BackProjection')
            xlabel(app.UIAxes2, 'Pixels')
            ylabel(app.UIAxes2, 'Pixels')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Projection for Specific Angle')
            xlabel(app.UIAxes3, 'Beams')
            ylabel(app.UIAxes3, 'Projections')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Back Projection')
            xlabel(app.UIAxes4, 'Pixels')
            ylabel(app.UIAxes4, 'Pixels')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Phantom')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.PhantomFileRelativePathLabel = uilabel(app.UIFigure);
            app.PhantomFileRelativePathLabel.Text = {'Phantom File '; 'Relative Path'};
            app.PhantomFileRelativePathEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofDetectorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDetectorsEditFieldLabel.Text = 'Number of Detectors';
            app.NumberofDetectorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectionAngleStepSizeindegreesEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectionAngleStepSizeindegreesEditFieldLabel.Text = 'Projection Angle Step Size (in degrees)';
            app.ProjectionAngleStepSizeindegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PROJECTButton = uibutton(app.UIFigure, 'push');
            app.PROJECTButton.ButtonPushedFcn = createCallbackFcn(app, @PROJECTButtonPushed, true);
            app.PROJECTButton.Text = 'PROJECT';
            app.BACKPROJECTButton = uibutton(app.UIFigure, 'push');
            app.BACKPROJECTButton.ButtonPushedFcn = createCallbackFcn(app, @BACKPROJECTButtonPushed, true);
            app.BACKPROJECTButton.Text = 'BACK PROJECT';
            app.SpecificAngleEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificAngleEditFieldLabel.Text = 'Specific  Angle ';
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.NoneButton = uiradiobutton(app.FilterButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.RamLakButton = uiradiobutton(app.FilterButtonGroup);
            app.RamLakButton.Text = 'Ram-Lak';
            app.HammingButton = uiradiobutton(app.FilterButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.BartlettHannButton = uiradiobutton(app.FilterButtonGroup);
            app.BartlettHannButton.Text = 'Bartlett-Hann';
            app.SpecificAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthoftheDetectorEditFieldLabel = uilabel(app.UIFigure);
            app.LengthoftheDetectorEditFieldLabel.Text = 'Length of the Detector';
            app.LengthoftheDetectorEditField = uieditfield(app.UIFigure, 'numeric');
            app.SourcetoDetectorDistanceinpixelsEditFieldLabel = uilabel(app.UIFigure);
            app.SourcetoDetectorDistanceinpixelsEditFieldLabel.Text = 'Source to Detector Distance (in pixels)';
            app.SourcetoDetectorDistanceinpixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeleteprojectionswhenquittingCheckBox = uicheckbox(app.UIFigure);
            app.DeleteprojectionswhenquittingCheckBox.Text = 'Delete projections when quitting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end